classdef DobotTicTacToeGUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        JogPanel                      matlab.ui.container.Panel
        xSliderLabel                  matlab.ui.control.Label
        xSlider                       matlab.ui.control.Slider
        ySliderLabel                  matlab.ui.control.Label
        ySlider                       matlab.ui.control.Slider
        zSliderLabel                  matlab.ui.control.Label
        zSlider                       matlab.ui.control.Slider
        TeachPanel                    matlab.ui.container.Panel
        ShoulderSliderLabel           matlab.ui.control.Label
        ShoulderSlider                matlab.ui.control.Slider
        ElbowSliderLabel              matlab.ui.control.Label
        ElbowSlider                   matlab.ui.control.Slider
        WristSliderLabel              matlab.ui.control.Label
        WristSlider                   matlab.ui.control.Slider
        DobotTicTacToeLabel           matlab.ui.control.Label
        StartgameButton               matlab.ui.control.Button
        YourturnLampLabel             matlab.ui.control.Label
        StatusLamp                    matlab.ui.control.Lamp
        ESTOPButton                   matlab.ui.control.StateButton
        ResumeButton                  matlab.ui.control.Button
        SafetysymbolPanel             matlab.ui.container.Panel
        DistancefromfrontSlider       matlab.ui.control.Slider
        DistancefromfrontSliderLabel  matlab.ui.control.Label
        SimulatesafetysymbolButton_2  matlab.ui.control.StateButton
    end

    
    properties (Access = private)
        game;
    end
    
    methods (Access = private)
        
        function v = readJogSliders(app)
            % read jog sliders and snap to increments
            v(1) = round(app.xSlider.Value,1);
            v(2) = round(app.ySlider.Value,1);
            v(3) = round(app.zSlider.Value,1);
            app.xSlider.Value = v(1);
            app.ySlider.Value = v(2);
            app.zSlider.Value = v(3);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.game = DobotTicTacToe;
            app.game.dobot.Plot(app.game.dobot.qn);
        end

        % Value changed function: xSlider, ySlider, zSlider
        function jogSliderValueChanged(app, event)
            v = readJogSliders(app);
            while (~all(v == 0))
                v = readJogSliders(app)
                app.game.manual(v,0.1);
                pause(0.1);
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.JogPanel = uipanel(app.UIFigure);
            app.JogPanel.Title = 'Jog';
            app.xSliderLabel = uilabel(app.JogPanel);
            app.xSliderLabel.Text = 'x';
            app.xSlider = uislider(app.JogPanel);
            app.xSlider.Limits = [-1 1];
            app.xSlider.ValueChangedFcn = createCallbackFcn(app, @jogSliderValueChanged, true);
            app.ySliderLabel = uilabel(app.JogPanel);
            app.ySliderLabel.Text = 'y';
            app.ySlider = uislider(app.JogPanel);
            app.ySlider.Limits = [-1 1];
            app.ySlider.ValueChangedFcn = createCallbackFcn(app, @jogSliderValueChanged, true);
            app.zSliderLabel = uilabel(app.JogPanel);
            app.zSliderLabel.Text = 'z';
            app.zSlider = uislider(app.JogPanel);
            app.zSlider.Limits = [-1 1];
            app.zSlider.ValueChangedFcn = createCallbackFcn(app, @jogSliderValueChanged, true);
            app.TeachPanel = uipanel(app.UIFigure);
            app.TeachPanel.Title = 'Teach';
            app.ShoulderSliderLabel = uilabel(app.TeachPanel);
            app.ShoulderSliderLabel.Text = 'Shoulder';
            app.ShoulderSlider = uislider(app.TeachPanel);
            app.ShoulderSlider.Limits = [-1 1];
            app.ElbowSliderLabel = uilabel(app.TeachPanel);
            app.ElbowSliderLabel.Text = 'Elbow';
            app.ElbowSlider = uislider(app.TeachPanel);
            app.ElbowSlider.Limits = [-1 1];
            app.WristSliderLabel = uilabel(app.TeachPanel);
            app.WristSliderLabel.Text = 'Wrist';
            app.WristSlider = uislider(app.TeachPanel);
            app.WristSlider.Limits = [-1 1];
            app.DobotTicTacToeLabel = uilabel(app.UIFigure);
            app.DobotTicTacToeLabel.Text = 'Dobot Tic Tac Toe';
            app.StartgameButton = uibutton(app.UIFigure, 'push');
            app.StartgameButton.Text = 'Start game';
            app.YourturnLampLabel = uilabel(app.UIFigure);
            app.YourturnLampLabel.Text = 'Your turn';
            app.StatusLamp = uilamp(app.UIFigure);
            app.ESTOPButton = uibutton(app.UIFigure, 'state');
            app.ESTOPButton.Text = 'ESTOP';
            app.ResumeButton = uibutton(app.UIFigure, 'push');
            app.ResumeButton.Text = 'Resume';
            app.SafetysymbolPanel = uipanel(app.UIFigure);
            app.SafetysymbolPanel.Title = 'Safety symbol';
            app.DistancefromfrontSlider = uislider(app.SafetysymbolPanel);
            app.DistancefromfrontSliderLabel = uilabel(app.SafetysymbolPanel);
            app.DistancefromfrontSliderLabel.Text = 'Distance from front';
            app.SimulatesafetysymbolButton_2 = uibutton(app.SafetysymbolPanel, 'state');
            app.SimulatesafetysymbolButton_2.Text = 'Simulate safety symbol';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = DobotTicTacToeGUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end